classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SelectfileButton       matlab.ui.control.Button
        Wavfile1Button         matlab.ui.control.Button
        VolumeupSliderLabel    matlab.ui.control.Label
        VolumeupSlider         matlab.ui.control.Slider
        Wavfile2Button         matlab.ui.control.Button
        VolumedownSliderLabel  matlab.ui.control.Label
        VolumedownSlider       matlab.ui.control.Slider
    end

    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file,~] = uigetfile('handel.wav');
            app.Filepath.Value=file;
        end

        % Button pushed function: Wavfile1Button
        function Wavfile1ButtonPushed(app, event)
            [y,fs]=audioread(app.Filepath.Value);
            sound(y,fs);
        end

        % Button pushed function: Wavfile2Button
        function Wavfile2ButtonPushed(app, event)
            SampleSound=app.Filepath.value(1:73113);
            SampleSoundVup=SampleSound*10;
            sound(SampleSoundVup,fs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.Wavfile1Button = uibutton(app.UIFigure, 'push');
            app.Wavfile1Button.ButtonPushedFcn = createCallbackFcn(app, @Wavfile1ButtonPushed, true);
            app.Wavfile1Button.Text = 'Wav file 1';
            app.VolumeupSliderLabel = uilabel(app.UIFigure);
            app.VolumeupSliderLabel.Text = 'Volume up:';
            app.VolumeupSlider = uislider(app.UIFigure);
            app.Wavfile2Button = uibutton(app.UIFigure, 'push');
            app.Wavfile2Button.ButtonPushedFcn = createCallbackFcn(app, @Wavfile2ButtonPushed, true);
            app.Wavfile2Button.Text = 'Wav file 2';
            app.VolumedownSliderLabel = uilabel(app.UIFigure);
            app.VolumedownSliderLabel.Text = 'Volume down:';
            app.VolumedownSlider = uislider(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end